classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SolutionMethodDropDownLabel    matlab.ui.control.Label
        SolutionMethodDropDown         matlab.ui.control.DropDown
        ThermalConductivityEditFieldLabel  matlab.ui.control.Label
        ThermalConductivityEditField   matlab.ui.control.NumericEditField
        DensityEditFieldLabel          matlab.ui.control.Label
        DensityEditField               matlab.ui.control.NumericEditField
        SpecificheatcapacityEditFieldLabel  matlab.ui.control.Label
        SpecificheatcapacityEditField  matlab.ui.control.NumericEditField
        MaxtimeEditFieldLabel          matlab.ui.control.Label
        MaxtimeEditField               matlab.ui.control.NumericEditField
        SpacialstepsEditFieldLabel     matlab.ui.control.Label
        SpacialstepsEditField          matlab.ui.control.NumericEditField
        TimestepsEditFieldLabel        matlab.ui.control.Label
        TimestepsEditField             matlab.ui.control.NumericEditField
        TileThicknessEditFieldLabel    matlab.ui.control.Label
        TileThicknessEditField         matlab.ui.control.NumericEditField
        Image                          matlab.ui.control.Image
        Tile502                        matlab.ui.control.Button
        Tile590                        matlab.ui.control.Button
        Tile597                        matlab.ui.control.Button
        Tile468                        matlab.ui.control.Button
        Tile480                        matlab.ui.control.Button
        Tile850                        matlab.ui.control.Button
        Tile711                        matlab.ui.control.Button
        Tile730                        matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function Plot(app, tile_number)
            [x, t, u] = shuttle(app.MaxtimeEditField.Value,...
                app.TimestepsEditField.Value, app.TileThicknessEditField.Value,...
                app.SpacialstepsEditField.Value, app.SolutionMethodDropDown.Value,...
                tile_number, app.ThermalConductivityEditField.Value,...
                app.DensityEditField.Value, app.SpecificheatcapacityEditField.Value);
            waterfall(app.UIAxes, x ,t, u)
            view(app.UIAxes, 130,30)

            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            scangraphs
        end

        % Button pushed function: Tile502
        function Tile502Pushed(app, event)
            app.Plot(502)
        end

        % Button pushed function: Tile590
        function Tile590Pushed(app, event)
            app.Plot(590)
        end

        % Button pushed function: Tile597
        function Tile597Pushed(app, event)
            app.Plot(597)
        end

        % Button pushed function: Tile468
        function Tile468Pushed(app, event)
            app.Plot(468)
        end

        % Button pushed function: Tile480
        function Tile480Pushed(app, event)
            app.Plot(480)
        end

        % Button pushed function: Tile850
        function Tile850Pushed(app, event)
            app.Plot(850)
        end

        % Button pushed function: Tile711
        function Tile711Pushed(app, event)
            app.Plot(711)
        end

        % Button pushed function: Tile730
        function Tile730Pushed(app, event)
            app.Plot(730)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SolutionMethodDropDownLabel = uilabel(app.UIFigure);
            app.SolutionMethodDropDownLabel.Text = 'Solution Method';
            app.SolutionMethodDropDown = uidropdown(app.UIFigure);
            app.SolutionMethodDropDown.Items = {'Forward Differencing', 'Dufort-Frankel', 'Backward Differencing', 'Crank-Nicolson'};
            app.SolutionMethodDropDown.Value = 'Forward Differencing';
            app.ThermalConductivityEditFieldLabel = uilabel(app.UIFigure);
            app.ThermalConductivityEditFieldLabel.Text = 'Thermal Conductivity';
            app.ThermalConductivityEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThermalConductivityEditField.Value = 0.0577;
            app.DensityEditFieldLabel = uilabel(app.UIFigure);
            app.DensityEditFieldLabel.Text = 'Density';
            app.DensityEditField = uieditfield(app.UIFigure, 'numeric');
            app.DensityEditField.Value = 144;
            app.SpecificheatcapacityEditFieldLabel = uilabel(app.UIFigure);
            app.SpecificheatcapacityEditFieldLabel.Text = 'Specific heat capacity';
            app.SpecificheatcapacityEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpecificheatcapacityEditField.Value = 1262;
            app.MaxtimeEditFieldLabel = uilabel(app.UIFigure);
            app.MaxtimeEditFieldLabel.Text = 'Max time';
            app.MaxtimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxtimeEditField.Value = 4000;
            app.SpacialstepsEditFieldLabel = uilabel(app.UIFigure);
            app.SpacialstepsEditFieldLabel.Text = 'Spacial steps';
            app.SpacialstepsEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpacialstepsEditField.Value = 21;
            app.TimestepsEditFieldLabel = uilabel(app.UIFigure);
            app.TimestepsEditFieldLabel.Text = 'Time steps';
            app.TimestepsEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimestepsEditField.Value = 501;
            app.TileThicknessEditFieldLabel = uilabel(app.UIFigure);
            app.TileThicknessEditFieldLabel.Text = 'Tile Thickness';
            app.TileThicknessEditField = uieditfield(app.UIFigure, 'numeric');
            app.TileThicknessEditField.Value = 0.05;
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'shuttle.jpg';
            app.Tile502 = uibutton(app.UIFigure, 'push');
            app.Tile502.ButtonPushedFcn = createCallbackFcn(app, @Tile502Pushed, true);
            app.Tile502.Text = '502';
            app.Tile590 = uibutton(app.UIFigure, 'push');
            app.Tile590.ButtonPushedFcn = createCallbackFcn(app, @Tile590Pushed, true);
            app.Tile590.Text = '590';
            app.Tile597 = uibutton(app.UIFigure, 'push');
            app.Tile597.ButtonPushedFcn = createCallbackFcn(app, @Tile597Pushed, true);
            app.Tile597.Text = '597';
            app.Tile468 = uibutton(app.UIFigure, 'push');
            app.Tile468.ButtonPushedFcn = createCallbackFcn(app, @Tile468Pushed, true);
            app.Tile468.Text = '468';
            app.Tile480 = uibutton(app.UIFigure, 'push');
            app.Tile480.ButtonPushedFcn = createCallbackFcn(app, @Tile480Pushed, true);
            app.Tile480.Text = '480';
            app.Tile850 = uibutton(app.UIFigure, 'push');
            app.Tile850.ButtonPushedFcn = createCallbackFcn(app, @Tile850Pushed, true);
            app.Tile850.Text = '850';
            app.Tile711 = uibutton(app.UIFigure, 'push');
            app.Tile711.ButtonPushedFcn = createCallbackFcn(app, @Tile711Pushed, true);
            app.Tile711.Text = '711';
            app.Tile730 = uibutton(app.UIFigure, 'push');
            app.Tile730.ButtonPushedFcn = createCallbackFcn(app, @Tile730Pushed, true);
            app.Tile730.Text = '730';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Heat Transfer')
            xlabel(app.UIAxes, 'Tile Thickness (m)')
            ylabel(app.UIAxes, 'Time (s)')
            zlabel(app.UIAxes, 'Temperature (^oC)')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end