classdef app1_autoreflow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        SolutionMethodDropDownLabel    matlab.ui.control.Label
        SolutionMethodDropDown         matlab.ui.control.DropDown
        ThermalConductivityEditFieldLabel  matlab.ui.control.Label
        ThermalConductivityEditField   matlab.ui.control.NumericEditField
        DensityEditFieldLabel          matlab.ui.control.Label
        DensityEditField               matlab.ui.control.NumericEditField
        SpecificheatcapacityEditFieldLabel  matlab.ui.control.Label
        SpecificheatcapacityEditField  matlab.ui.control.NumericEditField
        TileThicknessEditFieldLabel    matlab.ui.control.Label
        TileThicknessEditField         matlab.ui.control.NumericEditField
        Image                          matlab.ui.control.Image
        Tile502                        matlab.ui.control.Button
        Tile590                        matlab.ui.control.Button
        Tile597                        matlab.ui.control.Button
        Tile480                        matlab.ui.control.Button
        Tile850                        matlab.ui.control.Button
        Tile711                        matlab.ui.control.Button
        Tile730                        matlab.ui.control.Button
        Tile468                        matlab.ui.control.Button
        SpacialstepsEditFieldLabel     matlab.ui.control.Label
        SpacialstepsEditField          matlab.ui.control.NumericEditField
        TimestepsEditFieldLabel        matlab.ui.control.Label
        TimestepsEditField             matlab.ui.control.NumericEditField
        MaxtimeEditFieldLabel          matlab.ui.control.Label
        MaxtimeEditField               matlab.ui.control.NumericEditField
        Label                          matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = private)
        
        function Plot(app, tile_number)
            [x, t, u] = shuttle(app.MaxtimeEditField.Value,...
                app.TimestepsEditField.Value, app.TileThicknessEditField.Value,...
                app.SpacialstepsEditField.Value, app.SolutionMethodDropDown.Value,...
                tile_number, app.ThermalConductivityEditField.Value,...
                app.DensityEditField.Value, app.SpecificheatcapacityEditField.Value);
            waterfall(app.UIAxes, x ,t, u)
            view(app.UIAxes, 130,30)
            %zlim(app.UIAxes, [min(t) inf])

            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            scangraphs
            drawnow;
            app.UIFigure.WindowState = 'maximized';
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {779, 779};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {638, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: Tile502
        function Tile502Pushed(app, event)
            app.Plot(502)
        end

        % Button pushed function: Tile590
        function Tile590Pushed(app, event)
            app.Plot(590)
        end

        % Button pushed function: Tile597
        function Tile597Pushed(app, event)
            app.Plot(597)
        end

        % Button pushed function: Tile468
        function Tile468Pushed(app, event)
            app.Plot(468)
        end

        % Button pushed function: Tile480
        function Tile480Pushed(app, event)
            app.Plot(480)
        end

        % Button pushed function: Tile850
        function Tile850Pushed(app, event)
            app.Plot(850)
        end

        % Button pushed function: Tile711
        function Tile711Pushed(app, event)
            app.Plot(711)
        end

        % Button pushed function: Tile730
        function Tile730Pushed(app, event)
            app.Plot(730)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SolutionMethodDropDownLabel = uilabel(app.LeftPanel);
            app.SolutionMethodDropDownLabel.Text = 'Solution Method';
            app.SolutionMethodDropDown = uidropdown(app.LeftPanel);
            app.SolutionMethodDropDown.Items = {'Forward Differencing', 'Dufort-Frankel', 'Backward Differencing', 'Crank-Nicolson'};
            app.SolutionMethodDropDown.Value = 'Forward Differencing';
            app.ThermalConductivityEditFieldLabel = uilabel(app.LeftPanel);
            app.ThermalConductivityEditFieldLabel.Text = 'Thermal Conductivity';
            app.ThermalConductivityEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ThermalConductivityEditField.Value = 0.0577;
            app.DensityEditFieldLabel = uilabel(app.LeftPanel);
            app.DensityEditFieldLabel.Text = 'Density';
            app.DensityEditField = uieditfield(app.LeftPanel, 'numeric');
            app.DensityEditField.Value = 144;
            app.SpecificheatcapacityEditFieldLabel = uilabel(app.LeftPanel);
            app.SpecificheatcapacityEditFieldLabel.Text = 'Specific heat capacity';
            app.SpecificheatcapacityEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SpecificheatcapacityEditField.Value = 1262;
            app.TileThicknessEditFieldLabel = uilabel(app.LeftPanel);
            app.TileThicknessEditFieldLabel.Text = 'Tile Thickness';
            app.TileThicknessEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TileThicknessEditField.Value = 0.05;
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = 'shuttle.jpg';
            app.Tile502 = uibutton(app.LeftPanel, 'push');
            app.Tile502.ButtonPushedFcn = createCallbackFcn(app, @Tile502Pushed, true);
            app.Tile502.Text = '502';
            app.Tile590 = uibutton(app.LeftPanel, 'push');
            app.Tile590.ButtonPushedFcn = createCallbackFcn(app, @Tile590Pushed, true);
            app.Tile590.Text = '590';
            app.Tile597 = uibutton(app.LeftPanel, 'push');
            app.Tile597.ButtonPushedFcn = createCallbackFcn(app, @Tile597Pushed, true);
            app.Tile597.Text = '597';
            app.Tile480 = uibutton(app.LeftPanel, 'push');
            app.Tile480.ButtonPushedFcn = createCallbackFcn(app, @Tile480Pushed, true);
            app.Tile480.Text = '480';
            app.Tile850 = uibutton(app.LeftPanel, 'push');
            app.Tile850.ButtonPushedFcn = createCallbackFcn(app, @Tile850Pushed, true);
            app.Tile850.Text = '850';
            app.Tile711 = uibutton(app.LeftPanel, 'push');
            app.Tile711.ButtonPushedFcn = createCallbackFcn(app, @Tile711Pushed, true);
            app.Tile711.Text = '711';
            app.Tile730 = uibutton(app.LeftPanel, 'push');
            app.Tile730.ButtonPushedFcn = createCallbackFcn(app, @Tile730Pushed, true);
            app.Tile730.Text = '730';
            app.Tile468 = uibutton(app.LeftPanel, 'push');
            app.Tile468.ButtonPushedFcn = createCallbackFcn(app, @Tile468Pushed, true);
            app.Tile468.Text = '468';
            app.SpacialstepsEditFieldLabel = uilabel(app.LeftPanel);
            app.SpacialstepsEditFieldLabel.Text = 'Spacial steps';
            app.SpacialstepsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SpacialstepsEditField.Value = 21;
            app.TimestepsEditFieldLabel = uilabel(app.LeftPanel);
            app.TimestepsEditFieldLabel.Text = 'Time steps';
            app.TimestepsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TimestepsEditField.Value = 501;
            app.MaxtimeEditFieldLabel = uilabel(app.LeftPanel);
            app.MaxtimeEditFieldLabel.Text = 'Max time';
            app.MaxtimeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MaxtimeEditField.Value = 4000;
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = {'The graph images are scanned on app startup.'; ''; 'Click a tile to view the temperature profile through it through space shuttle re-entry.'; ''; 'Adjust the solution method, tile properties and numerical method parameters above the space shuttle.'};
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 'Tile Thickness (m)')
            ylabel(app.UIAxes, 'Time (s)')
            zlabel(app.UIAxes, 'Temperature (K)')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1_autoreflow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end